classdef mapas_color < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MapasdecolorLabel             matlab.ui.control.Label
        CargarimagenButton            matlab.ui.control.Button
        ElijalaescaladecolorDropDownLabel  matlab.ui.control.Label
        ElijalaescaladecolorDropDown  matlab.ui.control.DropDown
        ElijalaescaladecolorDropDownLabel_2  matlab.ui.control.Label
        AceptarButton                 matlab.ui.control.Button
        ImagenOriginalLabel           matlab.ui.control.Label
        TransformacinLabel            matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imagePath = '' % Description
    end
    
    methods (Access = private)
        
        function colormap(app)
            I = imread(app.imagePath);                 
            Itrans = zeros(size(I));
            [n,m,~] = size(I);
            t = mean(I,3);
            t = reshape(t, [], n*m);
            if strcmp(app.ElijalaescaladecolorDropDown.Value,'Gray')
                x=t;
                y=t;
                z=t;
            elseif strcmp(app.ElijalaescaladecolorDropDown.Value,'Cool')
                x = 255*cos(t./255);
                y = t;
                z = 2*255*cos(t./255);
            elseif strcmp(app.ElijalaescaladecolorDropDown.Value,'Dark')           
                x = 255-4*t;
                y = 255-3*t;
                z = 255-2*t;
            elseif strcmp(app.ElijalaescaladecolorDropDown.Value,'Inferno') 
                x = 255*sin(t./255);
                y = 50*cos(t./255);
                z = (2*t-1)./255;
            end
            x = reshape(x,[n, m]);
            y = reshape(y,[n, m]);
            z = reshape(z,[n, m]);
            Itrans(:,:,1) = x;
            Itrans(:,:,2) = y;
            Itrans(:,:,3) = z;
            I2 = imread(app.imagePath);
            imshow(uint8(I2),'Parent',app.UIAxes_2);
            imshow(uint8(Itrans),'Parent',app.UIAxes);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AceptarButton.Enable = false; 
            app.UIAxes.Visible = false;
            app.UIAxes_2.Visible = false;
        end

        % Button pushed function: CargarimagenButton
        function CargarimagenButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.*';'*.jpg';'*.png';'*.jpeg';'*.bmp'},'Busque un archivo de imagen');
            app.imagePath = strcat(filepath, filename);   
            app.AceptarButton.Enable = true; 
            if app.AceptarButton.Enable == true
                app.colormap();
            end
        end

        % Button pushed function: AceptarButton
        function AceptarButtonPushed(app, event)
            app.colormap();
        end

        % Value changed function: ElijalaescaladecolorDropDown
        function ElijalaescaladecolorDropDownValueChanged(app, event)
            if app.AceptarButton.Enable == true
                app.colormap();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MapasdecolorLabel = uilabel(app.UIFigure);
            app.MapasdecolorLabel.Text = 'Mapas de color';
            app.CargarimagenButton = uibutton(app.UIFigure, 'push');
            app.CargarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarimagenButtonPushed, true);
            app.CargarimagenButton.Text = 'Cargar imagen';
            app.ElijalaescaladecolorDropDownLabel = uilabel(app.UIFigure);
            app.ElijalaescaladecolorDropDownLabel.Text = 'Elija la escala de color';
            app.ElijalaescaladecolorDropDown = uidropdown(app.UIFigure);
            app.ElijalaescaladecolorDropDown.Items = {'Cool', 'Dark', 'Inferno', 'Gray'};
            app.ElijalaescaladecolorDropDown.ValueChangedFcn = createCallbackFcn(app, @ElijalaescaladecolorDropDownValueChanged, true);
            app.ElijalaescaladecolorDropDown.Value = 'Cool';
            app.ElijalaescaladecolorDropDownLabel_2 = uilabel(app.UIFigure);
            app.ElijalaescaladecolorDropDownLabel_2.Text = 'Cargue una imagen ';
            app.AceptarButton = uibutton(app.UIFigure, 'push');
            app.AceptarButton.ButtonPushedFcn = createCallbackFcn(app, @AceptarButtonPushed, true);
            app.AceptarButton.Text = 'Aceptar';
            app.ImagenOriginalLabel = uilabel(app.UIFigure);
            app.ImagenOriginalLabel.Text = 'Imagen Original';
            app.TransformacinLabel = uilabel(app.UIFigure);
            app.TransformacinLabel.Text = 'Transformación';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mapas_color

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end