classdef Proyecto1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GrabarAudioButton           matlab.ui.control.Button
        ECUALIZADORDESONIDOLabel    matlab.ui.control.Label
        VentanasDropDownLabel       matlab.ui.control.Label
        VentanasDropDown            matlab.ui.control.DropDown
        AceptarButton               matlab.ui.control.Button
        SegundosSpinnerLabel        matlab.ui.control.Label
        SegundosSpinner             matlab.ui.control.Spinner
        Label                       matlab.ui.control.Label
        Slider                      matlab.ui.control.Slider
        Label_2                     matlab.ui.control.Label
        Slider_2                    matlab.ui.control.Slider
        Label_3                     matlab.ui.control.Label
        Slider_3                    matlab.ui.control.Slider
        Label_4                     matlab.ui.control.Label
        Slider_4                    matlab.ui.control.Slider
        Label_5                     matlab.ui.control.Label
        Slider_5                    matlab.ui.control.Slider
        Label_6                     matlab.ui.control.Label
        Slider_6                    matlab.ui.control.Slider
        Label_7                     matlab.ui.control.Label
        Slider_7                    matlab.ui.control.Slider
        Label_8                     matlab.ui.control.Label
        Slider_8                    matlab.ui.control.Slider
        Label_9                     matlab.ui.control.Label
        Slider_9                    matlab.ui.control.Slider
        Label_10                    matlab.ui.control.Label
        Slider_10                   matlab.ui.control.Slider
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_4                    matlab.ui.control.UIAxes
        PaulaLorenaLpezRomeroLabel  matlab.ui.control.Label
        LauraAlejandraSalazarPerezLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        recObj = audiorecorder; % Description
    end
    
    
    methods (Access = private)
       function Fxs = Ventana_Rectangular(app,f, a)
            x = getaudiodata(app.recObj);
            x = x./max(x); % Description
            Fx = fft(x); % Algoritmo para calcular los valores de la Transformada de Fourier
            Fxs = fftshift(Fx); % Corrimiento de la transformada para obtener valores negativos y positivos.
            lista_intervalos = [0,31,63,125,250,500,1000,2000,4000,8000,160000]; %Las bandas de frecuencia
            ventana_rectangular = zeros(size(Fxs));
            for i=1:length(lista_intervalos)-1
                banda_inf = knnsearch(f',lista_intervalos(i));
                banda_sup = knnsearch(f',lista_intervalos(i+1));
                banda_infn = knnsearch(f',-lista_intervalos(i+1));
                banda_supn = knnsearch(f', -lista_intervalos(i));
                ventana_rectangular(banda_inf:banda_sup) = 10^(a(i)/10);
                ventana_rectangular(banda_infn:banda_supn) = 10^(a(i)/10);
            end
            Fxs = times(Fxs,ventana_rectangular);
       end
       function Fxs = Ventana_Hanning(app,a)
            x = getaudiodata(app.recObj);
            x = x./max(x);
            fs = app.recObj.SampleRate;
            n = length(x);
            f = (-n/2:n/2-1)*fs/n;
            Fx = fft(x); % Algoritmo para calcular los valores de la Transformada de Fourier
            Fxs = fftshift(Fx); % Corrimiento de la transformada para obtener valores negativos y positivos.
            ventana_hanning = zeros(size(Fxs));
            lista_intervalos = [0,31,63,125,250,500,1000,2000,4000,8000,160000];
            for i=1:length(lista_intervalos)-1 
                banda_inf = knnsearch(f',lista_intervalos(i));
                banda_sup = knnsearch(f',lista_intervalos(i+1));
                banda_infn = knnsearch(f',-lista_intervalos(i+1));
                banda_supn = knnsearch(f', -lista_intervalos(i));
                trans = f';
                len = trans(banda_inf:banda_sup);
                len2 = trans(banda_infn:banda_supn);
                ventana_hanning(banda_inf:banda_sup) = 10^(a(i)/10)*hann(length(len));
                ventana_hanning(banda_infn:banda_supn) = 10^(a(i)/10)*hann(length(len2));
            end
            Fxs = times(Fxs, ventana_hanning);
       end
       function f  = frecuencia(app)
           x = getaudiodata(app.recObj);
           x = x./max(x);
           fs = app.recObj.SampleRate;
           n = length(x);
           f = (-n/2:n/2-1)*fs/n;
           sound(x,fs)
           
       end
       function a = list_banda(app)
           a = [app.Slider.Value()
               app.Slider_2.Value()
               app.Slider_3.Value()
               app.Slider_4.Value()
               app.Slider_5.Value()
               app.Slider_6.Value()
               app.Slider_7.Value()
               app.Slider_8.Value()
               app.Slider_9.Value()
               app.Slider_10.Value()];
           
       end
       function y1 = plot_senal(app,Ventana)
           x = getaudiodata(app.recObj);
           x = x./max(x);
           fs = app.recObj.SampleRate;
           Fx = fft(x);
           Fxs = fftshift(Fx);
           n = length(x);
           f = (-n/2:n/2-1)*fs/n;
           t = (0:n-1)/fs;
           Fx3 = ifftshift(Ventana);
           y1 = real(ifft(Fx3));
           y1 = y1./max(y1);
           plot(app.UIAxes,t,x);
           plot(app.UIAxes_2,f,abs(Fxs));
           plot(app.UIAxes_4,f,angle(Fxs));
           plot(app.UIAxes_3,t,y1);
                      
       end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SliderValueChanged(app, event)
     
        end

        % Callback function: GrabarAudioButton, SegundosSpinner, 
        % SegundosSpinner
        function CargarAudioButtonPushed(app, event)
            app.recObj;
            t_grab = app.SegundosSpinner.Value();
            disp('Comienza la grabación')
            recordblocking(app.recObj, t_grab);
            disp('Termina la grabación')
            frecuencia(app);
        end

        % Callback function
        function Time(app, event)
         
        end

        % Value changed function: VentanasDropDown
        function Ventanas(app, event)
            value = app.VentanasDropDown.Value;
            
        end

        % Button pushed function: AceptarButton
        function Aceptar_ventana(app, event)
            fs = app.recObj.SampleRate;
            if  strcmpi(app.VentanasDropDown.Value(), "Ventana Rectangular")
                ven = Ventana_Rectangular(app,list_banda(app));
                sound(plot_senal(app,ven),fs)
            elseif strcmpi(app.VentanasDropDown.Value(), "Ventana Hanning")
                ven = Ventana_Hanning(app,list_banda(app));
                sound(plot_senal(app,ven),fs)
            end
            
        end

        % Callback function
        function Aceptar_cte(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GrabarAudioButton = uibutton(app.UIFigure, 'push');
            app.GrabarAudioButton.ButtonPushedFcn = createCallbackFcn(app, @CargarAudioButtonPushed, true);
            app.GrabarAudioButton.Text = 'Grabar Audio';
            app.ECUALIZADORDESONIDOLabel = uilabel(app.UIFigure);
            app.ECUALIZADORDESONIDOLabel.Text = 'ECUALIZADOR DE SONIDO';
            app.VentanasDropDownLabel = uilabel(app.UIFigure);
            app.VentanasDropDownLabel.Text = 'Ventanas';
            app.VentanasDropDown = uidropdown(app.UIFigure);
            app.VentanasDropDown.Items = {'Ventana Rectangular', 'Ventana Hanning'};
            app.VentanasDropDown.ValueChangedFcn = createCallbackFcn(app, @Ventanas, true);
            app.VentanasDropDown.Value = 'Ventana Hanning';
            app.AceptarButton = uibutton(app.UIFigure, 'push');
            app.AceptarButton.ButtonPushedFcn = createCallbackFcn(app, @Aceptar_ventana, true);
            app.AceptarButton.Text = 'Aceptar';
            app.SegundosSpinnerLabel = uilabel(app.UIFigure);
            app.SegundosSpinnerLabel.Text = 'Segundos';
            app.SegundosSpinner = uispinner(app.UIFigure);
            app.SegundosSpinner.ValueChangingFcn = createCallbackFcn(app, @CargarAudioButtonPushed, true);
            app.SegundosSpinner.Limits = [3 10];
            app.SegundosSpinner.ValueChangedFcn = createCallbackFcn(app, @CargarAudioButtonPushed, true);
            app.SegundosSpinner.Value = 3;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '31';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 20];
            app.Slider.MajorTicks = [];
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '63';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 20];
            app.Slider_2.MajorTicks = [];
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '125';
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [0 20];
            app.Slider_3.MajorTicks = [];
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = {'250'; ''};
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.Limits = [0 20];
            app.Slider_4.MajorTicks = [];
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '500';
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.Limits = [0 20];
            app.Slider_5.MajorTicks = [];
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '1000';
            app.Slider_6 = uislider(app.UIFigure);
            app.Slider_6.Limits = [0 20];
            app.Slider_6.MajorTicks = [];
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '2000';
            app.Slider_7 = uislider(app.UIFigure);
            app.Slider_7.Limits = [0 20];
            app.Slider_7.MajorTicks = [];
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '4000';
            app.Slider_8 = uislider(app.UIFigure);
            app.Slider_8.Limits = [0 20];
            app.Slider_8.MajorTicks = [];
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '8000';
            app.Slider_9 = uislider(app.UIFigure);
            app.Slider_9.Limits = [0 20];
            app.Slider_9.MajorTicks = [];
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '16000';
            app.Slider_10 = uislider(app.UIFigure);
            app.Slider_10.Limits = [0 20];
            app.Slider_10.MajorTicks = [0 10 20];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señal grabada ')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Amplitud')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTick = [-1 -0.5 0 0.5 1];
            app.UIAxes.YTickLabel = {'-1'; '-0.5'; '0'; '0.5'; '1'};
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Magnitud')
            xlabel(app.UIAxes_2, 'Frecuencia(Hz)')
            ylabel(app.UIAxes_2, 'Amplitud')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Señal recuperada con la ventana')
            xlabel(app.UIAxes_3, 'Tiempo(s)')
            ylabel(app.UIAxes_3, 'Amplitud')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Fase')
            xlabel(app.UIAxes_4, 'Frecuencia (Hz)')
            ylabel(app.UIAxes_4, 'Fase (radianes)')
            app.PaulaLorenaLpezRomeroLabel = uilabel(app.UIFigure);
            app.PaulaLorenaLpezRomeroLabel.Text = 'Paula Lorena López Romero';
            app.LauraAlejandraSalazarPerezLabel = uilabel(app.UIFigure);
            app.LauraAlejandraSalazarPerezLabel.Text = 'Laura Alejandra Salazar Perez';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proyecto1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end